classdef WordleSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WordleFigure                 matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        GameTab                      matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        NewGameButton_2              matlab.ui.control.Button
        Feedback                     matlab.ui.control.Label
        GuessPanel                   matlab.ui.container.Panel
        GuessLayout                  matlab.ui.container.GridLayout
        EditField_30                 matlab.ui.control.EditField
        EditField_29                 matlab.ui.control.EditField
        EditField_28                 matlab.ui.control.EditField
        EditField_27                 matlab.ui.control.EditField
        EditField_26                 matlab.ui.control.EditField
        EditField_25                 matlab.ui.control.EditField
        EditField_24                 matlab.ui.control.EditField
        EditField_23                 matlab.ui.control.EditField
        EditField_22                 matlab.ui.control.EditField
        EditField_21                 matlab.ui.control.EditField
        EditField_20                 matlab.ui.control.EditField
        EditField_19                 matlab.ui.control.EditField
        EditField_18                 matlab.ui.control.EditField
        EditField_17                 matlab.ui.control.EditField
        EditField_16                 matlab.ui.control.EditField
        EditField_15                 matlab.ui.control.EditField
        EditField_14                 matlab.ui.control.EditField
        EditField_13                 matlab.ui.control.EditField
        EditField_12                 matlab.ui.control.EditField
        EditField_11                 matlab.ui.control.EditField
        EditField_10                 matlab.ui.control.EditField
        EditField_09                 matlab.ui.control.EditField
        EditField_08                 matlab.ui.control.EditField
        EditField_07                 matlab.ui.control.EditField
        EditField_06                 matlab.ui.control.EditField
        EditField_05                 matlab.ui.control.EditField
        EditField_04                 matlab.ui.control.EditField
        EditField_03                 matlab.ui.control.EditField
        EditField_02                 matlab.ui.control.EditField
        EditField_01                 matlab.ui.control.EditField
        KeyboardPanel                matlab.ui.container.Panel
        KeyLayout                    matlab.ui.container.GridLayout
        BackspaceButton              matlab.ui.control.Button
        MButton                      matlab.ui.control.Button
        NButton                      matlab.ui.control.Button
        BButton                      matlab.ui.control.Button
        VButton                      matlab.ui.control.Button
        CButton                      matlab.ui.control.Button
        XButton                      matlab.ui.control.Button
        ZButton                      matlab.ui.control.Button
        ENTERButton                  matlab.ui.control.Button
        LButton                      matlab.ui.control.Button
        KButton                      matlab.ui.control.Button
        JButton                      matlab.ui.control.Button
        HButton                      matlab.ui.control.Button
        GButton                      matlab.ui.control.Button
        FButton                      matlab.ui.control.Button
        DButton                      matlab.ui.control.Button
        SButton                      matlab.ui.control.Button
        AButton                      matlab.ui.control.Button
        PButton                      matlab.ui.control.Button
        OButton                      matlab.ui.control.Button
        IButton                      matlab.ui.control.Button
        UButton                      matlab.ui.control.Button
        YButton                      matlab.ui.control.Button
        TButton                      matlab.ui.control.Button
        RButton                      matlab.ui.control.Button
        EButton                      matlab.ui.control.Button
        WButton                      matlab.ui.control.Button
        QButton                      matlab.ui.control.Button
        PickNextGuessButton          matlab.ui.control.Button
        StatsTab                     matlab.ui.container.Tab
        StatsTable                   matlab.ui.control.Table
        GuessesTabGroup              matlab.ui.container.TabGroup
        Guess1Tab                    matlab.ui.container.Tab
        SolverResultsLabel_1         matlab.ui.control.Label
        SolverResultsTable_1         matlab.ui.control.Table
        GoodGuessesListBox_1         matlab.ui.control.ListBox
        GoodguessesListBoxLabel      matlab.ui.control.Label
        Guess2Tab                    matlab.ui.container.Tab
        GoodGuessesListBox_2         matlab.ui.control.ListBox
        GoodguessesListBoxLabel_2    matlab.ui.control.Label
        SolverResultsLabel_2         matlab.ui.control.Label
        SolverResultsTable_2         matlab.ui.control.Table
        Guess3Tab                    matlab.ui.container.Tab
        GoodGuessesListBox_3         matlab.ui.control.ListBox
        GoodguessesListBoxLabel_3    matlab.ui.control.Label
        SolverResultsLabel_3         matlab.ui.control.Label
        SolverResultsTable_3         matlab.ui.control.Table
        Guess4Tab                    matlab.ui.container.Tab
        GoodGuessesListBox_4         matlab.ui.control.ListBox
        GoodguessesListBoxLabel_4    matlab.ui.control.Label
        SolverResultsLabel_4         matlab.ui.control.Label
        SolverResultsTable_4         matlab.ui.control.Table
        Guess5Tab                    matlab.ui.container.Tab
        GoodGuessesListBox_5         matlab.ui.control.ListBox
        GoodguessesListBoxLabel_5    matlab.ui.control.Label
        SolverResultsLabel_5         matlab.ui.control.Label
        SolverResultsTable_5         matlab.ui.control.Table
        Guess6Tab                    matlab.ui.container.Tab
        GoodGuessesListBox_6         matlab.ui.control.ListBox
        GoodguessesListBoxLabel_6    matlab.ui.control.Label
        SolverResultsLabel_6         matlab.ui.control.Label
        SolverResultsTable_6         matlab.ui.control.Table
        OptionsTab                   matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        Feedback_2                   matlab.ui.control.Label
        NewGameButton                matlab.ui.control.Button
        GameplayOptionsPanel         matlab.ui.container.Panel
        RestrictGoodGuessesCheckBox  matlab.ui.control.CheckBox
        GameModeLabel                matlab.ui.control.Label
        SolverMethodDropDown         matlab.ui.control.DropDown
        SolverMethodDropDownLabel    matlab.ui.control.Label
        HardmodeCheckBox             matlab.ui.control.CheckBox
        GameMode                     matlab.ui.control.DropDown
        SoverGuessesButtonGroup      matlab.ui.container.ButtonGroup
        SolverSGBListButton          matlab.ui.control.RadioButton
        SolverOtherGuessesFile       matlab.ui.control.EditField
        SolverOtherListButton        matlab.ui.control.RadioButton
        SolverLongListButton         matlab.ui.control.RadioButton
        SolverShortListButton        matlab.ui.control.RadioButton
        AcceptableGuessesListButtonGroup  matlab.ui.container.ButtonGroup
        OtherGuessesFile             matlab.ui.control.EditField
        OtherGuessesButton           matlab.ui.control.RadioButton
        StandardGuessesButton        matlab.ui.control.RadioButton
        AnswersListButtonGroup       matlab.ui.container.ButtonGroup
        OtherAnswerFile              matlab.ui.control.EditField
        OtherAnswerButton            matlab.ui.control.RadioButton
        StandardAnswerButton         matlab.ui.control.RadioButton
    end


    properties (Access = private)
        activeWord % Integer from 1-6 denoting the current guess
        activeLetter % Integer from 1-5 denoting the letter within the current guess
        answers % Cellstr array of answers to pick randomly from
        tiles % Array of editfield objects that hold the letters
        keys % Array of button objects that hold the keyboard keys
        answer % Answer in current game 
        acceptableGuesses % List of guesses that can be guessed
        solverData % Table of data used by the solver
        goodGuessLists % array of uilistbox object that hold the good guesses
        gameOver % Logical flag to denote the end of the game and prevent user input
        allSolverData % Data table used for all solvers
    end

    methods (Access = private)
        %% startNewGame - Reset the game interface and start a new game
        function startNewGame(app)

            % Load list of answer words and acceptable guesses
            if app.StandardAnswerButton.Value
                app.answers = app.allSolverData.Words{1};
            else
                app.answers = upper(readvars(app.OtherAnswerFile.Value,'TextType','string'));
            end
            if app.StandardGuessesButton.Value
                app.acceptableGuesses = app.allSolverData.Words{2};
            else
                app.acceptableGuesses = upper(readvars(app.OtherGuessesFile.Value,'TextType','string'));
            end

            % Reset position
            app.gameOver = false;
            app.activeLetter = 1;
            app.activeWord = 1;            

            % Empty the tiles and customize look
            for tile = app.tiles
                tile.Value = '';
                tile.FontColor = [0 0 0];
                tile.BackgroundColor = [1 1 1];
            end
            showFeedback(app,''); % Empty feedback boxes

            % Empty the keyboard buttons
            for key = app.keys
                key.FontColor = [0 0 0];
                key.BackgroundColor = 0.96+[0 0 0];
            end

            % Pick a word to be the answer
            rng shuffle
            if strcmp(app.GameMode.Value,'Known Answer')
                useLastAnswerInput = uiconfirm(app.WordleFigure,'Use previous answer?', ...
                    'Pick yes or no','Options',{'Yes','No'});
                if strcmp(useLastAnswerInput,'Yes') && ~isempty(app.answer)
                    answerInput = {app.answer};
                else
                    answerInput = inputdlg('Enter a 5-letter word for the answer');
                end
                if isempty(answerInput) || ~ismember(upper(answerInput{1}),app.acceptableGuesses)
                    showFeedback(app,'Invalid answer. Please start a new game');
                    app.gameOver = true;
                else
                    app.answer = upper(answerInput{1});
                end                
            else
                % Pick a random answer
                app.answer = app.answers{randi(length(app.answers),1)};
            end       

            % Pick a set of guesses for the solver
            solverListChoices = ["Answer","Acceptable Guesses","Stanford GraphBase"];
            indSolve = solverListChoices == extractBefore( ...
                app.SoverGuessesButtonGroup.SelectedObject.Text," List");
            if any(indSolve)
                app.solverData  = table(app.allSolverData.Words{indSolve}, ...
                    app.allSolverData.Probs{indSolve}, ...
                    app.allSolverData.ResultMatrix{indSolve}, ...
                    'VariableNames',["Words","LetterProbability","ResultMatrix"]);
            else
                newWords = upper(readvars(app.SolverOtherGuessesFile.Value,'TextType','string'));
                showFeedback(app,'Generating solver data. Please wait...')
                app.solverData = generateSolverData(newWords);
                showFeedback(app,'')
            end
           
            % Clear stats tab
            app.StatsTable.Data = []; 
            tabTables = findobj(app.GuessesTabGroup,'Type','uitable');
            tabLists = findobj(app.GuessesTabGroup,'Type','uilistbox');
            for i = 1:length(tabLists)
                tabTables(i).Data = [];
                tabLists(i).Items = {};
            end

            % Set of good guesses. They start out the same as the solver guesses
            % or the 2315 words in the Answer list
            app.goodGuessLists = findobj(app.GuessesTabGroup,'Type','uilistbox');
            if app.RestrictGoodGuessesCheckBox.Value
                goodGuesses = app.allSolverData.Words{solverListChoices=="Answer"};
            else
                goodGuesses = app.solverData.Words;
            end
            app.goodGuessLists(app.activeWord).Items = goodGuesses;

        end
        %% assembleWord - Get the guessed word from the filled in tiles
        function word = assembleWord(app,activeWord)
            if nargin<2
                activeWord = app.activeWord;
            end
            word = '';
            for i = 1:5
                k = i + (activeWord-1)*5;
                word(i) = app.tiles(k).Value;
            end
        end
        %% updateTiles - Change letters to green/yellow/gray based on guess
        function updateTiles(app,result,guess)
            for i = 1:5
                iTile = i + (app.activeWord-1)*5;
                iKey = strcmp({app.keys.Text},guess(i));
                switch result(i)
                    case 2 % Green
                        app.tiles(iTile).FontColor = [1 1 1];
                        app.tiles(iTile).BackgroundColor = [106 170 100]/255;
                        app.keys(iKey).FontColor = [1 1 1];
                        app.keys(iKey).BackgroundColor = [106 170 100]/255;
                    case 1 % Yellow
                        app.tiles(iTile).FontColor = [1 1 1];
                        app.tiles(iTile).BackgroundColor = [201 180 88]/255;
                        app.keys(iKey).FontColor = [1 1 1];
                        app.keys(iKey).BackgroundColor = [201 180 88]/255;
                    case 0 % Gray
                        app.tiles(iTile).FontColor = [1 1 1];
                        app.tiles(iTile).BackgroundColor = [134 136 138]/255;
                        app.keys(iKey).FontColor = [1 1 1];
                        app.keys(iKey).BackgroundColor = [134 136 138]/255;
                end
            end
        end
        %% playerInput - Handle keyboard presses
        function playerInput(app,key)
            if ~app.gameOver    
                % Backspace - Erase letter in tile
                if strcmp(key,'backspace')
                    if app.activeLetter>=1
                        app.activeLetter = max(1,app.activeLetter-1);
                        k = app.activeLetter + (app.activeWord-1)*5;
                        app.tiles(k).Value = '';
                    end
                % Letter - Add to tile and advance
                elseif length(key)==1 && ismember(upper(key),'A':'Z')
                    if app.activeLetter <= 5
                        k = app.activeLetter + (app.activeWord-1)*5;
                        app.tiles(k).Value = upper(key);
                        app.activeLetter = min(6,app.activeLetter+1);
                    end
                % Return - process answer
                elseif strcmp(key,'return') && app.activeLetter==6
                    
                    % Get guess from tiles
                    guess = assembleWord(app);                    

                    % Apply hard mode if checked
                    if app.HardmodeCheckBox.Value && app.activeWord>1
                        previousResult = getResultFromTiles(app,app.activeWord-1);
                        previousGuess = assembleWord(app,app.activeWord-1);
                        [meetsHardMode,feedback] = checkHardMode(guess,previousGuess,previousResult);
                        if ~meetsHardMode
                            showFeedback(app,feedback);
                            return;
                        end                       
                    end   

                    % Check for valid word
                    if ismember(guess,app.acceptableGuesses)
                        showFeedback(app,''); 
                        checkAnswer(app,guess);
                        updateStats(app);                        
                    else
                        msg = 'Please enter a valid 5-letter word';
                        if contains(app.Feedback.Text,msg)
                            app.Feedback.Text = [app.Feedback.Text '!'];
                        else
                            app.Feedback.Text = msg;
                        end
                    end
                end
            end
        end
        %%  getResultFromTiles - Read off green/gray/yellow from tiles
        function result = getResultFromTiles(app,activeWord)
            if nargin < 2
                activeWord = app.activeWord;
            end
            result = zeros(1,5);
             for i = 1:5
                iTile = i + (activeWord-1)*5;
                if app.tiles(iTile).BackgroundColor(1) == 106/255
                    result(i) = 2;
                elseif app.tiles(iTile).BackgroundColor(1) == 201/255
                    result(i) = 1;
                end
             end
            
        end
        %% filterGoodGuess - narrow down good guesses based on previous results
        function numRemaining = filterGoodGuesses(app)
             % Filter list based on previous results
            letterBounds = repmat([0;5],1,26);
            potentialGuesses = app.goodGuessLists(app.activeWord-1).Items;
            for i = 1:app.activeWord-1

                % Get wordle result
                guess = assembleWord(app,i);
                result = getResultFromTiles(app,i);
               
                % Filter down guesses and good guesses
                [isGoodGuess,letterBounds] = filterGuesses(potentialGuesses, ...
                    guess,result,letterBounds);
                potentialGuesses = potentialGuesses(isGoodGuess);                                
            end

            % Update next set of good guesses
            if ~app.gameOver
                app.goodGuessLists(app.activeWord).Items = potentialGuesses;
            end
            numRemaining = length(potentialGuesses);
        end
        %% checkAnswer - Get result of guess (yellow/green/gray)
        function checkAnswer(app,guess)
            if strcmp(app.GameMode.Value,'Unknown Answer')
                result = inputdlg('Enter the result in a continuous string of digits, e.g. 21001 (2=Green, 1=Yellow, 0=Gray', ...
                    'Wordle Result');
                result = double(result{1}) - 48; % '0' is char(48), so subtract off 48 to convert to double             
            else
                result = wordleGuess(guess,app.answer);                
            end
            updateTiles(app,result,guess)
            if all(result==2)
                app.Feedback.Text = 'Great job!';
                app.gameOver = true;
            elseif app.activeWord == 6
                if strcmp(app.GameMode.Value,'Unknown Answer')
                    app.Feedback.Text = ['Sorry, you''re out of guesses'];                    
                else
                    app.Feedback.Text = ['Sorry, the answer was: ' app.answer];
                end
                app.gameOver = true;
            end
            app.activeWord = app.activeWord + 1;
            app.activeLetter = 1;            
        end
        
        %% updateStats - Show good guesses and stats on Stats Tab      
        function updateStats(app)

            % Filter hard mode if needed
            previousGuess = assembleWord(app,app.activeWord-1);
            previousResult = getResultFromTiles(app,app.activeWord-1);
            if app.HardmodeCheckBox.Value && app.activeWord>1                                
                meetsHardMode = cellfun(@checkHardMode,app.solverData.Words, ...
                    repmat({previousGuess},height(app.solverData),1), ...
                    repmat({previousResult},height(app.solverData),1));
                app.solverData = app.solverData(meetsHardMode,:);
                app.solverData.ResultMatrix = app.solverData.ResultMatrix(:,meetsHardMode);
            end

            % Update good guesses
            numPotentialGuesses = length(app.goodGuessLists(app.activeWord-1).Items);
            numRemaining = filterGoodGuesses(app);
            numGuessesRemoved = numPotentialGuesses - numRemaining;
            
            % Display stats
            app.StatsTable.Data = [app.StatsTable.Data; table( ...
                app.activeWord-1,{previousGuess},{erase(num2str(previousResult),' ')}, ...
                numPotentialGuesses,numGuessesRemoved,numRemaining)];            

        end
        
        function showFeedback(app,str)
            app.Feedback.Text = str;
            app.Feedback_2.Text = str;            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            load WordleSolverData T
            app.allSolverData = T;

            % Get tile objects
            app.tiles = [];
            for tile = findobj(app.GuessLayout,'Type','uieditfield')'                
                tile.FontSize = 24;
                tile.HorizontalAlignment = 'center';
                tile.FontWeight = 'bold';
                app.tiles = [app.tiles tile];                
            end

            % Get keyboard objects
            app.keys = [];
            for key = findobj(app.KeyLayout,'Type','uibutton')'                
                key.FontSize = 18;
                app.keys = [app.keys key];                
            end

            % Start a new game
            startNewGame(app);
        end

        % Window key press function: WordleFigure
        function WordleFigureWindowKeyPress(app, event)
            key = event.Key;
            if app.activeWord<=6
                playerInput(app,key);
            end
        end

        % Button pushed function: NewGameButton, NewGameButton_2
        function NewGameButtonPushed(app, event)
            startNewGame(app);
            figure(app.WordleFigure)
        end

        % Button pushed function: AButton, BButton, BackspaceButton, 
        % ...and 25 other components
        function KeyboardButtonPushed(app, event)
            key = event.Source.Text;
            if strcmp(key,'←')
                key = 'backspace';
            elseif strcmp(key,'ENTER')
                key = 'return';
            end
            if app.activeWord <=6
                playerInput(app,key);
            end
            figure(app.WordleFigure)
        end

        % Button pushed function: PickNextGuessButton
        function PickNextGuessButtonPushed(app, event)

            % End early if game over
            if app.gameOver
                return;
            end

            % Try to pick a guess if we only have 1 or 2 left
            nextGuess = ''; 
            goodGuesses = app.goodGuessLists(app.activeWord).Items;
            if isempty(goodGuesses)
                showFeedback(app,'Ran out of good guesses');
             elseif length(goodGuesses)<3
                nextGuess = goodGuesses{1};
                solverInfo = [];
            end

            % Pick solver method
            method = app.SolverMethodDropDown.Value;
            if ismember(method,["MinMax","Constellations","MaxEntropy"]) && isempty(nextGuess)
                [nextGuess,solverInfo] = pickNextGuessFast(goodGuesses,app.solverData,method);
            elseif strcmp(method,'Random') && isempty(nextGuess)
                nextGuess = goodGuesses{randi(length(goodGuesses),1)};
                solverInfo = [];
            elseif strcmp(method,'Last chance')
                if app.activeWord<6
                    allGuesses = app.solverData.Words;
                    nextGuess = allGuesses{randi(length(allGuesses),1)};
                else
                    nextGuess = goodGuesses{randi(length(goodGuesses),1)};
                end                    
                solverInfo = [];
            elseif strcmp(method,'TikTok')
                words = {'GLENT','BRICK','JUMPY','VOZHD','WAQFS'};
                if app.activeWord < 6
                    nextGuess = words{app.activeWord};
                else
                    nextGuess = goodGuesses{randi(length(goodGuesses),1)};
                end
                solverInfo = [];
            end
            

            %Update the guess stats stable
            if ~isempty(solverInfo)
                tabObj = app.GuessesTabGroup.Children(app.activeWord).Children;
                for obj = tabObj'
                    if strcmp(obj.Type,'uitable')
                        obj.Data = solverInfo;
                        obj.ColumnName = solverInfo.Properties.VariableNames;
                    end
                end
            end

            % Fill in the guess on the game board
            for i = 1:5
                k = i + (app.activeWord-1)*5;
                app.tiles(k).Value = nextGuess(i);
            end
            app.activeLetter = 6;
            figure(app.WordleFigure)
        end

        % Callback function
        function GuessBrowseButtonPushed(app, event)
            [file,path] = uigetfile("*.*","Select an acceptable guesses file");
            app.OtherGuessesFile.Value = fullfile(path,file);
            app.OtherGuessesButton.Value = true;
            figure(app.WordleFigure)
        end

        % Callback function
        function SolverBrowseButtonPushed(app, event)
            [file,path] = uigetfile("*.*","Select an solver guesses file");
            app.SolverOtherGuessesFile.Value = fullfile(path,file);
            app.SolverOtherListButton.Value = true;
            figure(app.WordleFigure)
        end

        % Value changed function: GameMode, HardmodeCheckBox, 
        % ...and 2 other components
        function OptionsChanged(app, event)
            showFeedback(app,'Change in options detected. Start new game to continue');
            app.gameOver = true;            
        end

        % Selection changed function: AcceptableGuessesListButtonGroup, 
        % ...and 2 other components
        function FileListSelectionChanged(app, event)
            
            % Get objects
            selectedButton = event.NewValue;
            isEditField = strcmp({event.Source.Children.Type},'uieditfield');
            editField = event.Source.Children(isEditField);

            % Open a uigetfile if selection is 'Other
            if strcmp(selectedButton.Text,'Other') && isempty(editField.Value)
                [file,path] = uigetfile("*.*","Select an answers file");
                editField.Value = fullfile(path,file);
            end

            % Call OptionsChanged to force a new game
            OptionsChanged(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WordleFigure = uifigure('Visible', 'off');
            app.WordleFigure.Name = 'WORDLAB';
            app.WordleFigure.WindowKeyPressFcn = createCallbackFcn(app, @WordleFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.WordleFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GameTab = uitab(app.TabGroup);
            app.GameTab.Title = 'Game';
            app.GridLayout2 = uigridlayout(app.GameTab);
            app.PickNextGuessButton = uibutton(app.GridLayout2, 'push');
            app.PickNextGuessButton.ButtonPushedFcn = createCallbackFcn(app, @PickNextGuessButtonPushed, true);
            app.PickNextGuessButton.Text = 'Pick Next Guess';
            app.KeyboardPanel = uipanel(app.GridLayout2);
            app.KeyLayout = uigridlayout(app.KeyboardPanel);
            app.QButton = uibutton(app.KeyLayout, 'push');
            app.QButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.QButton.Text = 'Q';
            app.WButton = uibutton(app.KeyLayout, 'push');
            app.WButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.WButton.Text = 'W';
            app.EButton = uibutton(app.KeyLayout, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.EButton.Text = 'E';
            app.RButton = uibutton(app.KeyLayout, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.RButton.Text = 'R';
            app.TButton = uibutton(app.KeyLayout, 'push');
            app.TButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.TButton.Text = 'T';
            app.YButton = uibutton(app.KeyLayout, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.YButton.Text = 'Y';
            app.UButton = uibutton(app.KeyLayout, 'push');
            app.UButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.UButton.Text = 'U';
            app.IButton = uibutton(app.KeyLayout, 'push');
            app.IButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.IButton.Text = 'I';
            app.OButton = uibutton(app.KeyLayout, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.OButton.Text = 'O';
            app.PButton = uibutton(app.KeyLayout, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.PButton.Text = 'P';
            app.AButton = uibutton(app.KeyLayout, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.AButton.Text = 'A';
            app.SButton = uibutton(app.KeyLayout, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.SButton.Text = 'S';
            app.DButton = uibutton(app.KeyLayout, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.DButton.Text = 'D';
            app.FButton = uibutton(app.KeyLayout, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.FButton.Text = 'F';
            app.GButton = uibutton(app.KeyLayout, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.GButton.Text = 'G';
            app.HButton = uibutton(app.KeyLayout, 'push');
            app.HButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.HButton.Text = 'H';
            app.JButton = uibutton(app.KeyLayout, 'push');
            app.JButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.JButton.Text = 'J';
            app.KButton = uibutton(app.KeyLayout, 'push');
            app.KButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.KButton.Text = 'K';
            app.LButton = uibutton(app.KeyLayout, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.LButton.Text = 'L';
            app.ENTERButton = uibutton(app.KeyLayout, 'push');
            app.ENTERButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.ENTERButton.Text = 'ENTER';
            app.ZButton = uibutton(app.KeyLayout, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.ZButton.Text = 'Z';
            app.XButton = uibutton(app.KeyLayout, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.XButton.Text = 'X';
            app.CButton = uibutton(app.KeyLayout, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.CButton.Text = 'C';
            app.VButton = uibutton(app.KeyLayout, 'push');
            app.VButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.VButton.Text = 'V';
            app.BButton = uibutton(app.KeyLayout, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.BButton.Text = 'B';
            app.NButton = uibutton(app.KeyLayout, 'push');
            app.NButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.NButton.Text = 'N';
            app.MButton = uibutton(app.KeyLayout, 'push');
            app.MButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.MButton.Text = 'M';
            app.BackspaceButton = uibutton(app.KeyLayout, 'push');
            app.BackspaceButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardButtonPushed, true);
            app.BackspaceButton.Text = '←';
            app.GuessPanel = uipanel(app.GridLayout2);
            app.GuessLayout = uigridlayout(app.GuessPanel);
            app.EditField_01 = uieditfield(app.GuessLayout, 'text');
            app.EditField_02 = uieditfield(app.GuessLayout, 'text');
            app.EditField_03 = uieditfield(app.GuessLayout, 'text');
            app.EditField_04 = uieditfield(app.GuessLayout, 'text');
            app.EditField_05 = uieditfield(app.GuessLayout, 'text');
            app.EditField_06 = uieditfield(app.GuessLayout, 'text');
            app.EditField_07 = uieditfield(app.GuessLayout, 'text');
            app.EditField_08 = uieditfield(app.GuessLayout, 'text');
            app.EditField_09 = uieditfield(app.GuessLayout, 'text');
            app.EditField_10 = uieditfield(app.GuessLayout, 'text');
            app.EditField_11 = uieditfield(app.GuessLayout, 'text');
            app.EditField_12 = uieditfield(app.GuessLayout, 'text');
            app.EditField_13 = uieditfield(app.GuessLayout, 'text');
            app.EditField_14 = uieditfield(app.GuessLayout, 'text');
            app.EditField_15 = uieditfield(app.GuessLayout, 'text');
            app.EditField_16 = uieditfield(app.GuessLayout, 'text');
            app.EditField_17 = uieditfield(app.GuessLayout, 'text');
            app.EditField_18 = uieditfield(app.GuessLayout, 'text');
            app.EditField_19 = uieditfield(app.GuessLayout, 'text');
            app.EditField_20 = uieditfield(app.GuessLayout, 'text');
            app.EditField_21 = uieditfield(app.GuessLayout, 'text');
            app.EditField_22 = uieditfield(app.GuessLayout, 'text');
            app.EditField_23 = uieditfield(app.GuessLayout, 'text');
            app.EditField_24 = uieditfield(app.GuessLayout, 'text');
            app.EditField_25 = uieditfield(app.GuessLayout, 'text');
            app.EditField_26 = uieditfield(app.GuessLayout, 'text');
            app.EditField_27 = uieditfield(app.GuessLayout, 'text');
            app.EditField_28 = uieditfield(app.GuessLayout, 'text');
            app.EditField_29 = uieditfield(app.GuessLayout, 'text');
            app.EditField_30 = uieditfield(app.GuessLayout, 'text');
            app.Feedback = uilabel(app.GridLayout2);
            app.Feedback.Text = '';
            app.NewGameButton_2 = uibutton(app.GridLayout2, 'push');
            app.NewGameButton_2.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton_2.Text = 'New Game';
            app.StatsTab = uitab(app.TabGroup);
            app.StatsTab.Title = 'Stats';
            app.GuessesTabGroup = uitabgroup(app.StatsTab);
            app.Guess1Tab = uitab(app.GuessesTabGroup);
            app.Guess1Tab.Title = 'Guess 1';
            app.GoodguessesListBoxLabel = uilabel(app.Guess1Tab);
            app.GoodguessesListBoxLabel.Text = 'Good guesses';
            app.GoodGuessesListBox_1 = uilistbox(app.Guess1Tab);
            app.GoodGuessesListBox_1.Tag = 'goodGuesses';
            app.SolverResultsTable_1 = uitable(app.Guess1Tab);
            app.SolverResultsTable_1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3.'; 'Column4'};
            app.SolverResultsTable_1.RowName = {};
            app.SolverResultsLabel_1 = uilabel(app.Guess1Tab);
            app.SolverResultsLabel_1.Text = 'Solver results';
            app.Guess2Tab = uitab(app.GuessesTabGroup);
            app.Guess2Tab.Title = 'Guess 2';
            app.SolverResultsTable_2 = uitable(app.Guess2Tab);
            app.SolverResultsTable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3.'; 'Column4'};
            app.SolverResultsTable_2.RowName = {};
            app.SolverResultsLabel_2 = uilabel(app.Guess2Tab);
            app.SolverResultsLabel_2.Text = 'Solver results';
            app.GoodguessesListBoxLabel_2 = uilabel(app.Guess2Tab);
            app.GoodguessesListBoxLabel_2.Text = 'Good guesses';
            app.GoodGuessesListBox_2 = uilistbox(app.Guess2Tab);
            app.GoodGuessesListBox_2.Tag = 'goodGuesses';
            app.Guess3Tab = uitab(app.GuessesTabGroup);
            app.Guess3Tab.Title = 'Guess 3';
            app.SolverResultsTable_3 = uitable(app.Guess3Tab);
            app.SolverResultsTable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3.'; 'Column4'};
            app.SolverResultsTable_3.RowName = {};
            app.SolverResultsLabel_3 = uilabel(app.Guess3Tab);
            app.SolverResultsLabel_3.Text = 'Solver results';
            app.GoodguessesListBoxLabel_3 = uilabel(app.Guess3Tab);
            app.GoodguessesListBoxLabel_3.Text = 'Good guesses';
            app.GoodGuessesListBox_3 = uilistbox(app.Guess3Tab);
            app.Guess4Tab = uitab(app.GuessesTabGroup);
            app.Guess4Tab.Title = 'Guess 4';
            app.SolverResultsTable_4 = uitable(app.Guess4Tab);
            app.SolverResultsTable_4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3.'; 'Column4'};
            app.SolverResultsTable_4.RowName = {};
            app.SolverResultsLabel_4 = uilabel(app.Guess4Tab);
            app.SolverResultsLabel_4.Text = 'Solver results';
            app.GoodguessesListBoxLabel_4 = uilabel(app.Guess4Tab);
            app.GoodguessesListBoxLabel_4.Text = 'Good guesses';
            app.GoodGuessesListBox_4 = uilistbox(app.Guess4Tab);
            app.Guess5Tab = uitab(app.GuessesTabGroup);
            app.Guess5Tab.Title = 'Guess 5';
            app.SolverResultsTable_5 = uitable(app.Guess5Tab);
            app.SolverResultsTable_5.ColumnName = {'Column 1'; 'Column 2'; 'Column 3.'; 'Column4'};
            app.SolverResultsTable_5.RowName = {};
            app.SolverResultsLabel_5 = uilabel(app.Guess5Tab);
            app.SolverResultsLabel_5.Text = 'Solver results';
            app.GoodguessesListBoxLabel_5 = uilabel(app.Guess5Tab);
            app.GoodguessesListBoxLabel_5.Text = 'Good guesses';
            app.GoodGuessesListBox_5 = uilistbox(app.Guess5Tab);
            app.Guess6Tab = uitab(app.GuessesTabGroup);
            app.Guess6Tab.Title = 'Guess 6';
            app.SolverResultsTable_6 = uitable(app.Guess6Tab);
            app.SolverResultsTable_6.ColumnName = {'Column 1'; 'Column 2'; 'Column 3.'; 'Column4'};
            app.SolverResultsTable_6.RowName = {};
            app.SolverResultsLabel_6 = uilabel(app.Guess6Tab);
            app.SolverResultsLabel_6.Text = 'Solver results';
            app.GoodguessesListBoxLabel_6 = uilabel(app.Guess6Tab);
            app.GoodguessesListBoxLabel_6.Text = 'Good guesses';
            app.GoodGuessesListBox_6 = uilistbox(app.Guess6Tab);
            app.StatsTable = uitable(app.StatsTab);
            app.StatsTable.ColumnName = {'Guess'; 'Word'; 'Result'; 'Guesses Before'; 'Guesses Removed'; 'Guesses After'};
            app.StatsTable.RowName = {};
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.GridLayout3 = uigridlayout(app.OptionsTab);
            app.AnswersListButtonGroup = uibuttongroup(app.GridLayout3);
            app.AnswersListButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FileListSelectionChanged, true);
            app.AnswersListButtonGroup.Tooltip = {'List of words used as answers when game mode is "Random Answer". The standard list is the same as Wordle'};
            app.AnswersListButtonGroup.Title = 'Answers List';
            app.StandardAnswerButton = uiradiobutton(app.AnswersListButtonGroup);
            app.StandardAnswerButton.Text = 'Standard List (2315 words)';
            app.StandardAnswerButton.Value = true;
            app.OtherAnswerButton = uiradiobutton(app.AnswersListButtonGroup);
            app.OtherAnswerButton.Text = 'Other';
            app.OtherAnswerFile = uieditfield(app.AnswersListButtonGroup, 'text');
            app.AcceptableGuessesListButtonGroup = uibuttongroup(app.GridLayout3);
            app.AcceptableGuessesListButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FileListSelectionChanged, true);
            app.AcceptableGuessesListButtonGroup.Tooltip = {'List of words accepted as inputs. The standard list is the same as Wordle'};
            app.AcceptableGuessesListButtonGroup.Title = 'Acceptable Guesses List';
            app.StandardGuessesButton = uiradiobutton(app.AcceptableGuessesListButtonGroup);
            app.StandardGuessesButton.Text = 'Standard List (12972 words)';
            app.StandardGuessesButton.Value = true;
            app.OtherGuessesButton = uiradiobutton(app.AcceptableGuessesListButtonGroup);
            app.OtherGuessesButton.Text = 'Other';
            app.OtherGuessesFile = uieditfield(app.AcceptableGuessesListButtonGroup, 'text');
            app.SoverGuessesButtonGroup = uibuttongroup(app.GridLayout3);
            app.SoverGuessesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FileListSelectionChanged, true);
            app.SoverGuessesButtonGroup.Tooltip = {'List of words used by the built-in solver to pick guesses. For example, if the selection is "Answer List", then the solver will only pick words from the 2,315 words used by Wordle as answers.'};
            app.SoverGuessesButtonGroup.Title = 'Solver Guesses List';
            app.SolverShortListButton = uiradiobutton(app.SoverGuessesButtonGroup);
            app.SolverShortListButton.Text = 'Answer List (2315 words)';
            app.SolverShortListButton.Value = true;
            app.SolverLongListButton = uiradiobutton(app.SoverGuessesButtonGroup);
            app.SolverLongListButton.Text = 'Acceptable Guesses List (12972 words)';
            app.SolverOtherListButton = uiradiobutton(app.SoverGuessesButtonGroup);
            app.SolverOtherListButton.Text = 'Other';
            app.SolverOtherGuessesFile = uieditfield(app.SoverGuessesButtonGroup, 'text');
            app.SolverSGBListButton = uiradiobutton(app.SoverGuessesButtonGroup);
            app.SolverSGBListButton.Text = 'Stanford GraphBase List (5757 words)';
            app.GameplayOptionsPanel = uipanel(app.GridLayout3);
            app.GameplayOptionsPanel.Title = 'Gameplay Options';
            app.GameMode = uidropdown(app.GameplayOptionsPanel);
            app.GameMode.Items = {'Random Answer', 'Unknown Answer', 'Known Answer'};
            app.GameMode.ValueChangedFcn = createCallbackFcn(app, @OptionsChanged, true);
            app.GameMode.Tooltip = {'Random: pick an answer at random from the Answers List'; 'Known: Prompt user for an answer to use'; 'Unknown: Prompt user for result after each guess'};
            app.GameMode.Value = 'Random Answer';
            app.HardmodeCheckBox = uicheckbox(app.GameplayOptionsPanel);
            app.HardmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptionsChanged, true);
            app.HardmodeCheckBox.Tooltip = {'Turn on hard mode: Any revealed hints must be used in subsequent guesses'};
            app.HardmodeCheckBox.Text = 'Hard mode';
            app.SolverMethodDropDownLabel = uilabel(app.GameplayOptionsPanel);
            app.SolverMethodDropDownLabel.Text = 'Solver Method';
            app.SolverMethodDropDown = uidropdown(app.GameplayOptionsPanel);
            app.SolverMethodDropDown.Items = {'MinMax', 'Constellations', 'MaxEntropy', 'Random', 'Last chance', 'TikTok'};
            app.SolverMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsChanged, true);
            app.SolverMethodDropDown.Tooltip = {'Strategy selection for next guesses: '; 'MinMax: Donald Knuth''s Mastermind stategy adapted to Wordle. Maximizes the minimum number of words removed for each guess'; 'Constellations: Matt Tearle and 538''s strategy. Picks words with the largest number of results, or "constellations" '; 'Entropy: Picks words that maximize the expected information. From 3Blue1Brown, but doesn''t use their word likelihoods'; 'Random: Random guess using hard mode (i.e. uses all feedback)'; 'Last Chance: 5 completely random words, then whatever''s left for the 6th'; 'TikTok: Like Last chance, but with 5 words that use 25 letters (source: TikTok)'};
            app.SolverMethodDropDown.Value = 'MinMax';
            app.GameModeLabel = uilabel(app.GameplayOptionsPanel);
            app.GameModeLabel.Text = 'Game Mode';
            app.RestrictGoodGuessesCheckBox = uicheckbox(app.GameplayOptionsPanel);
            app.RestrictGoodGuessesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptionsChanged, true);
            app.RestrictGoodGuessesCheckBox.Tooltip = {'If checked, the list of good guesses is the 2315 words in the Answer List. Otherwise, its the full set of words in the Solver Guesses List'};
            app.RestrictGoodGuessesCheckBox.Text = 'Restrict good guesses';
            app.NewGameButton = uibutton(app.GridLayout3, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.Feedback_2 = uilabel(app.GridLayout3);
            app.Feedback_2.Text = '';
            % Show the figure after all components are created
            app.WordleFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WordleSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WordleFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WordleFigure)
        end
    end
end